classdef ExtractChannelsLandApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        R2D2SmoothingPanel             matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        SaveR2D2DEMButton              matlab.ui.control.Button
        NumR2D2LinesEditField          matlab.ui.control.NumericEditField
        NumR2D2LinesEditFieldLabel     matlab.ui.control.Label
        DEMDropDown                    matlab.ui.control.DropDown
        DEMDropDownLabel               matlab.ui.control.Label
        RunR2D2SmoothingButton         matlab.ui.control.Button
        ChannelExtractionPanel         matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        ExtractChannelButton           matlab.ui.control.Button
        MinDrainageAreaEditFieldLabel  matlab.ui.control.Label
        MinDrainageAreaEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
         % Description
    end
    
    properties (Access = public)
        MainApp
        PTS
        xyzFun
        ElevationDataFilename
        xLimits = [0 1];
        yLimits = [0 1];
        UIAxes
        ProgressBarButton
        TTC % TopoToolbox channels
        xyzFun_new % for R2D2 smoothing
        xyzFun_old %
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainApp = mainapp;
            app.PTS = mainapp.PTS;
            app.UIAxes = mainapp.UIAxes;
            app.ProgressBarButton = mainapp.ProgressBarButton;
            app.ElevationDataFilename = mainapp.ElevationDataFilename;
            app.xyzFun = mainapp.xyzFun;
            app.MinDrainageAreaEditField.Value = mainapp.MinDrainageArea;

            % move subapp to center of mainapp
            mainP = mainapp.UIFigure.Position;
            subP = app.UIFigure.Position;
            subP(1) = mainP(1) + 0.5*(mainP(3) - subP(3));
            subP(2) = mainP(2) + 0.5*(mainP(4) - subP(4));
            app.UIFigure.Position = subP;

            CheckElevationDataFile(app);

            % PlotEdgeStructure(app,.1);
        end

        % Button pushed function: ExtractChannelButton
        function ExtractChannelButtonPushed(app, event)
            ExtractChannelsLandSubdomain(app,'firstcall');
        end

        % Value changed function: MinDrainageAreaEditField
        function MinDrainageAreaEditFieldValueChanged(app, event)
            ExtractChannelsLandSubdomain(app,'update');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            CloseChannelsLandApp(app);
        end

        % Callback function
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.MinDrainageAreaEditField_3.Value = value;
            ExtractChannelsLandSubdomain(app,'update');
        end

        % Callback function
        function SliderValueChanging(app, event)
            value = event.Value;
            app.MinDrainageAreaEditField_3.Value = value;
            ExtractChannelsLandSubdomain(app,'update');
        end

        % Button pushed function: RunR2D2SmoothingButton
        function RunR2D2SmoothingButtonPushed(app, event)
            R2D2Smoothing(app,app.NumR2D2LinesEditField.Value);
        end

        % Callback function
        function ZoomInOn(app, event)
            % app.UIFigure.CurrentAxes = app.UIAxes;
            zoom(app.UIFigure,'on');
        end

        % Callback function
        function ZoomInOff(app, event)
            % app.UIFigure.CurrentAxes = app.UIAxes;
            zoom(app.UIFigure,'off');
        end

        % Callback function
        function ResetViewClicked(app, event)
            resetView(app);
        end

        % Callback function
        function ColormapDropDownValueChanged(app, event)
            SetContourStatus(app);
        end

        % Value changed function: DEMDropDown
        function DEMDropDownValueChanged(app, event)
            PlotR2D2Result(app);
        end

        % Button pushed function: SaveR2D2DEMButton
        function SaveR2D2DEMButtonPushed(app, event)
            SaveR2D2Raster(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ChannelExtractionPanel = uipanel(app.GridLayout);
            app.ChannelExtractionPanel.Title = 'Channel Extraction';
            app.GridLayout2 = uigridlayout(app.ChannelExtractionPanel);
            app.MinDrainageAreaEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MinDrainageAreaEditField.ValueChangedFcn = createCallbackFcn(app, @MinDrainageAreaEditFieldValueChanged, true);
            app.MinDrainageAreaEditField.Value = 100000;
            app.MinDrainageAreaEditFieldLabel = uilabel(app.GridLayout2);
            app.MinDrainageAreaEditFieldLabel.Text = 'Min Drainage Area';
            app.ExtractChannelButton = uibutton(app.GridLayout2, 'push');
            app.ExtractChannelButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractChannelButtonPushed, true);
            app.ExtractChannelButton.Text = 'Extract Channel';
            app.R2D2SmoothingPanel = uipanel(app.GridLayout);
            app.R2D2SmoothingPanel.Title = 'R2D2 Smoothing';
            app.GridLayout3 = uigridlayout(app.R2D2SmoothingPanel);
            app.RunR2D2SmoothingButton = uibutton(app.GridLayout3, 'push');
            app.RunR2D2SmoothingButton.ButtonPushedFcn = createCallbackFcn(app, @RunR2D2SmoothingButtonPushed, true);
            app.RunR2D2SmoothingButton.Enable = 'off';
            app.RunR2D2SmoothingButton.Text = 'Run R2D2 Smoothing';
            app.DEMDropDownLabel = uilabel(app.GridLayout3);
            app.DEMDropDownLabel.Text = 'DEM';
            app.DEMDropDown = uidropdown(app.GridLayout3);
            app.DEMDropDown.Items = {'R2D2', 'Raw'};
            app.DEMDropDown.ValueChangedFcn = createCallbackFcn(app, @DEMDropDownValueChanged, true);
            app.DEMDropDown.Enable = 'off';
            app.DEMDropDown.Value = 'R2D2';
            app.NumR2D2LinesEditFieldLabel = uilabel(app.GridLayout3);
            app.NumR2D2LinesEditFieldLabel.Text = 'Num. R2D2 Lines';
            app.NumR2D2LinesEditField = uieditfield(app.GridLayout3, 'numeric');
            app.NumR2D2LinesEditField.Enable = 'off';
            app.NumR2D2LinesEditField.Value = 10;
            app.SaveR2D2DEMButton = uibutton(app.GridLayout3, 'push');
            app.SaveR2D2DEMButton.ButtonPushedFcn = createCallbackFcn(app, @SaveR2D2DEMButtonPushed, true);
            app.SaveR2D2DEMButton.Enable = 'off';
            app.SaveR2D2DEMButton.Text = 'Save R2D2 DEM';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExtractChannelsLandApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end