classdef ExtractChannelsLandApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        MinDrainageAreaEditFieldLabel  matlab.ui.control.Label
        MinDrainageAreaEditField       matlab.ui.control.NumericEditField
        ExtractChannelButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp % Description
    end
    
    properties (Access = public)
        PTS
        xyzFun
        ElevationDataFilename
        ViewAxes
        ProgressBarButton
        TTC
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainApp = mainapp;
            app.PTS = mainapp.PTS;
            app.ViewAxes = mainapp.ViewAxes;
            app.ProgressBarButton = mainapp.ProgressBarButton;
            app.ElevationDataFilename = mainapp.ElevationDataFilename;
            app.xyzFun = mainapp.xyzFun;
            app.MinDrainageAreaEditField.Value = mainapp.MinDrainageArea;

            Position = mainapp.UIFigure.Position;
            movegui(app.UIFigure,Position(1:2) + Position(3:4).*[.4 .8])
        end

        % Button pushed function: ExtractChannelButton
        function ExtractChannelButtonPushed(app, event)
            ExtractChannelsLandSubdomain(app,'firstcall');
        end

        % Value changed function: MinDrainageAreaEditField
        function MinDrainageAreaEditFieldValueChanged(app, event)
            value = app.MinDrainageAreaEditField.Value;
            ExtractChannelsLandSubdomain(app,'update');
            app.MainApp.MinDrainageArea = app.MinDrainageAreaEditField.Value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.MainApp.MinDrainageArea = app.MinDrainageAreaEditField.Value;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ExtractChannelButton = uibutton(app.GridLayout, 'push');
            app.ExtractChannelButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractChannelButtonPushed, true);
            app.ExtractChannelButton.Text = 'Extract Channel';
            app.MinDrainageAreaEditField = uieditfield(app.GridLayout, 'numeric');
            app.MinDrainageAreaEditField.ValueChangedFcn = createCallbackFcn(app, @MinDrainageAreaEditFieldValueChanged, true);
            app.MinDrainageAreaEditField.Value = 100000;
            app.MinDrainageAreaEditFieldLabel = uilabel(app.GridLayout);
            app.MinDrainageAreaEditFieldLabel.Text = 'Min Drainage Area';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExtractChannelsLandApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end